classdef LEDStimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LogFileTab                      matlab.ui.container.Tab
        DisplayFileDirectoryButton      matlab.ui.control.Button
        AddCommentButton                matlab.ui.control.Button
        ChangeDirectoryButton           matlab.ui.control.Button
        CurrentDirectoryEditField       matlab.ui.control.EditField
        CurrentDirectoryEditFieldLabel  matlab.ui.control.Label
        MasterLedTab                    matlab.ui.container.Tab
        CurrentRepetitionEditField      matlab.ui.control.NumericEditField
        CurrentRepetitionEditFieldLabel  matlab.ui.control.Label
        CurrentTrialEditField           matlab.ui.control.NumericEditField
        CurrentTrialEditFieldLabel      matlab.ui.control.Label
        CurrentFOVEditField             matlab.ui.control.NumericEditField
        CurrentFOVEditFieldLabel        matlab.ui.control.Label
        RepeatTrialButton               matlab.ui.control.Button
        NewTrialButton                  matlab.ui.control.Button
        NewFOVButton                    matlab.ui.control.Button
        SpotLedTab                      matlab.ui.container.Tab
        Lamp                            matlab.ui.control.Lamp
        StatusEditField                 matlab.ui.control.NumericEditField
        StatusEditFieldLabel            matlab.ui.control.Label
        RepeatTrialButton_2             matlab.ui.control.Button
        StartTrialButton                matlab.ui.control.Button
        DurationsecEditField            matlab.ui.control.NumericEditField
        DurationsecEditFieldLabel       matlab.ui.control.Label
        NDStopEditField                 matlab.ui.control.NumericEditField
        NDStopEditFieldLabel            matlab.ui.control.Label
        StimulusSiteButtonGroup         matlab.ui.container.ButtonGroup
        leftrightButton                 matlab.ui.control.RadioButton
        onlyleftButton                  matlab.ui.control.RadioButton
        onlyrightButton                 matlab.ui.control.RadioButton
        Panel                           matlab.ui.container.Panel
        PretrigtimeEditField            matlab.ui.control.NumericEditField
        PretrigtimeEditFieldLabel       matlab.ui.control.Label
        InterstimEditField              matlab.ui.control.NumericEditField
        InterstimEditFieldLabel         matlab.ui.control.Label
        DurationEditField               matlab.ui.control.NumericEditField
        DurationEditFieldLabel          matlab.ui.control.Label
        IntensityIndex1601EditField     matlab.ui.control.NumericEditField
        IntensityIndex1601EditFieldLabel  matlab.ui.control.Label
        NumberofspotsEditField          matlab.ui.control.NumericEditField
        NumberofspotsEditFieldLabel     matlab.ui.control.Label
        NDStartEditField                matlab.ui.control.NumericEditField
        NDStartEditFieldLabel           matlab.ui.control.Label
        SpotColorDropDown               matlab.ui.control.DropDown
        SpotColorDropDownLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        %%%%%%
        %properties for log file dir window
        %%%%%%
        logfilepath %this is path that the files is saved at 
        
        %%%%%%
        %properties for master led window
        %%%%%%
        cellnumber
        trialnumber %this is the trial number
        repnumber %this is the repetition number

        %%%%%%
        %properties for spot led window
        %%%%%%
        tests %this is the test numbet NumberofspotsEditField,
        time %this is time
        tween
        pretrig
        IntensityIndex
        SIDEstate %the state
        clampex_duration%
        spot_color
        side%stimulus side

        whiteonblack%idk???
        
        ND
        currND
        NDstart%when to start
        NDstop%when to stop
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %the led stuff i think

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        BGstate
        sS
        sS2
        sND
        sMs
        sMe
        sD
        sD2
        sAIF
        fid1
        lh
        sAO1
        LOvoltage
        sON
        sOFF
        sRSTART
        sAO2
        optogenetics
    end
    
    
    methods (Access = private)

        function [] = increment_trial(app)
            % --- Increment trial number
            %increment trial number and display on user interface
            app.CurrentTrialEditField.Value=app.CurrentTrialEditField.Value+1;
            %reset each time incrementing cell number   (repnumber)
            app.CurrentRepetitionEditField=1;
            %define path and filename of log file for this cell, accessible (global) to all modules
            app.logfilepath = strcat(logfile_directory,'\fov#',num2str(app.cellnumber,'%02d'),'_','trial#',num2str(app.trialnumber,'%02d'),'_','rep#',num2str(app.repnumber,'%02d'),'_');
        end

        function increment_rep(app)
            % --- Increment repetition number
            %increment trial number and display on user interface
            app.CurrentRepetitionEditField=app.CurrentRepetitionEditField+1;
            %define path and filename of log file for this cell, accessible (global) to all modules
            app.logfilepath = strcat(logfile_directory,'\fov#',num2str(app.cellnumber,'%02d'),'_','trial#',num2str(app.trialnumber,'%02d'),'_','rep#',num2str(app.repnumber,'%02d'),'_');
        end
        
        function spotrun(app)
                jheapcl

                pulseND(app.currND, app.ND);
                pause (1)
                app.currND=app.ND;

                app.fid1 = fopen('log.bin','w');
                app.lh = app.sAIF.addlistener('DataAvailable',@(src, event)logData(src, event, app.fid1));

                %log start time to logfile
                startTime=datatime("now",21);
                start_time = clock;     %save start time to calculate elapsed time later
                expType='SP';


                if exist([app.logfilepath, 'logFile.mat'],'file')
                    warndlg('Warning - A file with a similar name already exists');
                    uiwait(gcf);
                    delete(app.lh);
                    fclose(app.fid1);
                    return
                end


                %DISABLE GUI UPDATE
                % set up communication to GUI to permit display of current stimulus
                hSpotGui = getappdata(0, 'hSpotGui'); % get handle to the GUI
                % get the handle to the callback for the "now playing" readut
                fh_now_playing_readout_Callback = getappdata(hSpotGui,'fh_now_playing_readout_Callback');
                % get access to the handles structure for the GUI
                handles = guidata(hSpotGui);

                %initialize local variables
                tested = [];  % declare array to hold sizes (rad) tested for logfile
                outtahere = 0;
                on = 1;

                %show the user via the GUI that the trial is running
                set(handles.now_playing_readout, 'String', 'running');  %update the string to be displayed
                feval(fh_now_playing_readout_Callback);  %invoke callback function for the readout
                guidata(hSpotGui, handles);   % Update handles structure

                %create vector for ramp
                ramp_vector = round(linspace(1, app.IntensityIndex, app.time/2));  %first half climing intensity
                %add decreasing intensity
                ramp_vector = [ramp_vector flip(ramp_vector)];

                tStart = tic;

                %%%%%%%%%%%%%%%%
                if app.optogenetics
                    for i=1:200
                        app.sAO2.outputSingleScan(5); %15 sec of stimulus before the trile
                        pause(0.05)
                        app.sAO2.outputSingleScan(0); %15 sec of stimulus before the trile
                        pause(0.05)
                    end
                end
                app.sRSTART.outputSingleScan(1);
                app.sMs.outputSingleScan(1); pause(0.1); app.sMs.outputSingleScan(0);
                app.sD.outputSingleScan(1); pause(0.1); app.sD.outputSingleScan(0);
                app.sAIF.startBackground();
                %%%%%%%%%%%%%%%%

                tElapsed = toc(tStart);
                pause(app.pretrig - tElapsed);
                if app.optogenetics
                    app.sAO2.outputSingleScan(0); %15 sec of stimulus before the trile
                end

                % % Generate sqaure wave output signals for triggering cameras
                %  sAO2.Rate=1000;
                %  addoutput(daq1,"Dev2","ao0","Voltage");             % set up an output channel to trigger cameras
                %
                %
                %
                % %  yn = [0 0 0 5 5 5 5 5 5 5 0 0 0]
                %  t = 0:1/1e3:140;                  % square wave sampled at 1 kHz (1/1E3)for 20 sec
                %  y = square(2*pi*100*t,30);       % generate a 100 Hz square wave, with a duty cycle of 30
                %  yn=5.*normalize(y,'range');      % normalize between 0 and 5
                %  yn(end) = [];                    % remove last element to ensure to continuity
                %  yn=[yn,yn(1:10)];                % add 10 elements at the end of yn
                %
                %  preload(sAO2, yn');                              % Preload the data for camera trigger
                %  start(sAO2);                                    % start output of camera trigger, and input of ball sensor (ai0-2) and camera trigger (ai3)
                %  pause(200)


                for i = 1 : app.tests

                    [~, ~,keyCode] = KbCheck;

                    if (on == 1)

                        %%%%%%%%%%%%%%%%
                        app.sD.outputSingleScan(1);
                        app.sON.outputSingleScan(1); pause(0.1); app.sON.outputSingleScan(0);
                        app.sAO1.outputSingleScan(app.LOvoltage(1));

                        %%%%%%%%%%%%%%%%

                        tested = [tested,1]; %append rad to 'tested' scalar
                        %rad = rad + step;
                        on = 0;


                        % We're in the holding period while spotLEDauto is being displayed.
                        % Chop interval ('time') into 1 s intervals to permit periodic check for
                        % user ESC keystroke, which triggers an abort
                        for k = 1:(app.time * 1)
                            app.sAO1.outputSingleScan(app.LOvoltage(ramp_vector(k))); %each seconed increas the intensity of light
                            pause(0.985)
                            [~,~,keyCode] = KbCheck;
                            keystrike = KbName(keyCode);
                            if strcmp(keystrike,'esc')
                                outtahere = 1;
                                %                 delete(lh);
                                %                 fclose(fid1);
                                break;

                            end
                        end
                    end

                    if (on==0)
                        if (outtahere > 0)     %bail out if user has hit ESC
                            break;
                        end


                        %%%%%%%%%%%%%%%%
                        app.sD.outputSingleScan(0);
                        app.sOFF.outputSingleScan(1); pause(0.1); app.sOFF.outputSingleScan(0);
                        app.sAO1.outputSingleScan(0);

                        if app.optogenetics
                            %turn on laser in the dark part of every 2 triels
                            if ~mod(i,2)
                                app.sAO2.outputSingleScan(5); %10 sec of stimulus between the triles
                            end
                        end


                        %%%%%%%%%%%%%%%%

                        % interstim interval
                        % chop into 1 s intervals to permit periodic check for
                        % user ESC keystroke, which triggers an abort
                        for b = 1:(app.tween*1)
                            pause(0.985)

                            [~,~,keyCode] = KbCheck;
                            keystrike = KbName(keyCode);
                            if strcmp(keystrike,'esc')
                                outtahere = 2;   %will exploit value of outtahere
                                %                 delete(lh);
                                %                 fclose(fid1);
                                break;           %when logging nature of abort to logfile
                            end
                        end

                        if outtahere > 0
                            %             delete(lh);
                            %             fclose(fid1);
                            %sS.outputSingleScan (1);
                            %sS.outputSingleScan (0);
                            break
                        end
                        if app.optogenetics
                            app.sAO2.outputSingleScan(0); %10 sec of stimulus every 2 triels
                        end

                        on = 1;
                    end

                end

                %%%%%%%%%%%%%%%%%%%%%%
                app.sD.outputSingleScan(0);
                app.sAIF.stop();
                app.sRSTART.outputSingleScan(0);
                app.sMe.outputSingleScan(1); pause(0.1); app.sMe.outputSingleScan(0);
                app.sAO1.outputSingleScan(0);
                if app.optogenetics
                    app.sAO2.outputSingleScan(0); %15 sec of stimulus before the trile
                end


                %%%%%%%%%%%%%%%%%%%%%%

                %code to display current value of radius on the GUI
                set(handles.now_playing_readout, 'String', 'done');  %update the string to be displayed
                feval(fh_now_playing_readout_Callback);  %invoke callback function for the readout
                guidata(hSpotGui, handles);   % Update handles structure

                delete(app.lh);
                fclose(app.fid1);

                fid2 = fopen('log.bin','r');
                [data,~]= fread(fid2,[3,inf],'double');
                fclose(fid2);
                data=data';
                data(:,2)=max(data(:,2))-data(:,2);
                frameWaveLog=data;

                %update log file with stoptime, elapsed time and parameters used
                end_time = clock;
                elapsed = etime(end_time,start_time);

                [~, stim_num] = size(tested);
                switch outtahere
                    case 0
                        abort_notice = '';
                    case 1
                        abort_notice = strcat(['  << aborted during stimulus ',num2str(stim_num), ' >>']);
                    case 2
                        abort_notice = strcat(['  << aborted during interstim interval after stim ',num2str(stim_num), ' >>']);
                end

                endTime=datestr(now,21);
                triel_voltage = app.LOvoltage(ramp_vector);
                save([app.logfilepath, 'logFile.mat'],'expType','cellnumber','trialnumber','repnumber','startTime','endTime','elapsed','tests','time','tween','whiteonblack', 'pretrig', 'IntensityIndex', 'ND', 'SIDEstate','frameWaveLog','abort_notice', 'triel_voltage');

        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChangeDirectoryButton
        function Change_dir_Button(app, event)
logfile_directory = uigetdir('D:\in vivo data');  %user specifies directory through browsing dialog box
app.CurrentDirectoryEditField.Value=logfile_directory;%setting dir and showing the user which directory was selected

app.logfilepath = strcat(logfile_directory,'\fov#',num2str(app.cellnumber,'%02d'),'_','trial#',num2str(app.trialnumber,'%02d'),'_','rep#',num2str(app.repnumber,'%02d'),'_');

        end

        % Button pushed function: DisplayFileDirectoryButton
        function display_logfile_button(app, event)
popupmessage(setPath,setPath)

        end

        % Button pushed function: AddCommentButton
        function add_comment_button(app, event)
            add_comment;
        end

        % Value changed function: SpotColorDropDown
        function SpotColorDropDownValueChanged(app, event)
            %PICK SPOTLEDAUTO COLOR
            switch app.SpotColorDropDown.Value%get
                case 'White'
                    app.spot_color = [255 255 255];  %white
                    app.Lamp.Color=[1.00,1.00,1.00];
                case 'Black'
                    app.spot_color = [0 0 0];        %black
                    app.Lamp.Color=[0.00,0.00,0.00]
                case 'Red'
                    app.spot_color = [255 0 0];      %red
                    app.Lamp.Color=[1.00,0.00,0.00]
                case 'Green'
                    app.spot_color = [0 255 0];      %green
                    app.Lamp.Color=[0.00,1.00,0.00]
                case 'Blue'
                    app.spot_color = [0 0 255];      %blue
                    app.Lamp.Color=[0.00,0.00,1.00]
            end
        end

        % Button pushed function: StartTrialButton
        function Start_Trial_Button(app, event)
            %START BUTTON
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            for i=app.NDStartEditField.Value:-1:app.NDStopEditField.Value
                try
                    delete(app.lh);
                    fclose(app.fid1);
                    fclose(fid2);
                catch
                end
                app.ND=i;
                increment_trial(app)
                spotrun(app)
            end
        end

        % Value changed function: NDStopEditField
        function NDstop_Fcn(app, event)
            
            temp_ND=app.NDStopEditField;%get user input
            if isempty(temp_ND) || temp_ND <= 0 || temp_ND > 12
                beep;
                if temp_ND > 12
                    f = errordlg('ND stop cant be more then 12','File Error');
                    temp_ND = 1;
                else
                    f = errordlg('ND stop incorrect input','File Error');
                    temp_ND = 1;
                end

                temp_ND = 1;
            end

            app.ND= temp_ND;%set
        end

        % Button pushed function: RepeatTrialButton_2
        function Repeat_Trial_Button(app, event)
            %repeat trial button
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            try
                delete(app.lh);
                fclose(app.fid1);
                fclose(fid2);
            catch
            end
            increment_rep(app)
            spotrun(app)
        end

        % Value changed function: NumberofspotsEditField
        function value_num_spots_change(app, event)
            %TESTS (i.e., number of sizes)
            temp_value = app.NumberofspotsEditField.Value;%get
            
            %error trap - tests must be a number and >0 and <=20
            if isempty(temp_value) || temp_value < 1 || temp_value > 200
                beep;
                if temp_value > 200
                    temp_value = 200;
                else
                    temp_value = 1;
                end
            end

            app.tests = temp_value;%set
            %calculate best duration for clampex protocol
            %safety factor is to make sure clampex protocol duration catches everything
            safety_factor = 1;
            app.clampex_duration = app.pretrig + (app.pretrig * (app.time + app.tween)) + safety_factor;
            app.DurationEditField=app.clampex_duration;


        end

        % Value changed function: DurationEditField
        function value_duration_change(app, event)
            %TIME (i.e. duration of each spotLEDauto presentation)
            % get user input for stimulus duration ('time')
            temp_value = app.DurationEditField.Value;%get

            %error trap - time must be a number and >10 ms and <=30 sec
            if isempty(temp_value) || temp_value < 0.01 || temp_value > 10000
                beep;
                if temp_value > 10000
                    temp_value = 30;
                else
                    temp_value = 2;
                end

                app.time = temp_value;
            end

            app.time = temp_value;%set

            %calculate and display best duration for clampex protocol
            %safety factor is to make sure clampex protocol duration catches everything
            safety_factor = 1;
            app.clampex_duration = app.pretrig + (app.tests * (app.time + app.tween)) + safety_factor;
            set(app.DurationsecEditField,'Value', app.clampex_duration);
        end

        % Value changed function: InterstimEditField
        function value_interstim_change(app, event)
            %TWEEN (i.e., interstim interval)
            temp_tween_value = app.InterstimEditField.Value;%get

            %error trap - ISI ('tween') must be a number and >0.1 s and <=30 sec
            if isempty(temp_tween_value) || temp_tween_value < 0.1 || temp_tween_value > 1000
                beep;
                %     if temp_tween > 1000
                %         temp_tween = 30;
                %     else
                %         temp_tween = 1;
                %     end

                app.tween=app.InterstimEditField.Value;
            end

            app.tween = temp_tween;%set
            %calculate and display best duration for clampex protocol
            %safety factor is to make sure clampex protocol duration catches everything
            safety_factor = 1;
            app.clampex_duration = app.pretrig + (app.tests * (app.time + app.tween)) + safety_factor;
            set(app.DurationsecEditField,'Value', app.clampex_duration);
            
        end

        % Value changed function: PretrigtimeEditField
        function pretrig_change(app, event)
            %PRETRIGGER
            temp_pretrig = app.PretrigtimeEditField.Value;%get

            %error trap - pretrig must be a number and >0 and <=30
            if isempty(temp_pretrig) || temp_pretrig <= 0 || temp_pretrig > 1000
                beep;
                if temp_pretrig > 1000
                    temp_pretrig = 30;
                else
                    temp_pretrig = 1;
                end
            end
            app.pretrig = temp_pretrig;%set
%set(handles.pretrig,'String', num2str(pretrig));

%calculate and display best duration for clampex protocol
            %safety factor is to make sure clampex protocol duration catches everything
            safety_factor = 1;
            app.clampex_duration = app.pretrig + (app.tests * (app.time + app.tween)) + safety_factor;
            set(app.DurationsecEditField,'Value', app.clampex_duration);

% --- Executes during object creation, after setting all properties.
        end

        % Value changed function: IntensityIndex1601EditField
        function IntensityIndex_change(app, event)
            temp_index = app.IntensityIndex1601EditField.Value;%get user input

            %error trap - IntensityIndex must be a number and >0 and <=1
            if isempty(temp_index)
                temp_index = 1;
            elseif temp_index > 6001
                temp_index = 6001;
            elseif temp_index < 1
                temp_index = 1;
            end

            app.IntensityIndex = temp_index;%set
            set(app.IntensityIndex1601EditField.Value,'String', num2str(app.IntensityIndex));

        end

        % Value changed function: NDStartEditField
        function NDstart_change(app, event)
            temp_ND = app.NDStartEditField.Value;%get user input
            %lobal NDstart

            if isempty(temp_ND) || temp_ND <= 0 || temp_ND > 12
                beep;
                 temp_ND = 1;
            end

            app.NDstart = temp_ND;
            set(app.NDStartEditField,'String', num2str(app.NDstart));

        end

        % Button down function: SpotLedTab
        function spotLEDauto_OpeningFcn(app, event)
            %this function is a restsrter of spot led page
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %LOIndex=1:6001; idk what this is
            app.LOvoltage=linspace(0,5,6001);
            %LOamper=linspace(0,1200,6001); idk what this is

            app.tests = 20;       %6 for focal cell, 2 for depth series, 5 for Rbp, 10 for IV, 4 for OFF alpha, 4 for excitatory currents ipRGCs
            app.time = 10;      % 30 for iGluSnFR, 15 for Rbp, 4 for IV
            app.tween = 10;      %15 for focal cell, 30 for depth series,7 for IV, 30 for OFF alpha
            app.pretrig = 15;    % 0.1721 for IV
            app.IntensityIndex = 6001;
            app.LOvoltage = app.LOvoltage;
            safety_factor = 1;
            app.whiteonblack = 1;
            app.spot_color = "White";       %white
            app.side = 3;  %both sides
            app.ND=1;%app.currND;
            app.Lamp.Color=[1.00,1.00,1.00];
            app.clampex_duration = app.pretrig + (app.tests * (app.time + app.tween)) + safety_factor;
            set(app.NumberofspotsEditField,'Value', app.tests);
            set(app.DurationEditField,'Value',app.time);
            set(app.InterstimEditField,'Value',app.tween);
            set(app.PretrigtimeEditField ,'Value', app.pretrig);
            set(app.IntensityIndex1601EditField,'Value', app.IntensityIndex);
            set(app.NDStartEditField,'Value', app.ND);
            set(app.DurationsecEditField,'Value', app.clampex_duration);
            %set(handles.now_playing_readout,'String', ''); idk what this is
            set(app.SpotColorDropDown,'Value', app.spot_color );
            %set(app.StimulusSiteButtonGroup,'value',app.side);
            app.SIDEstate=3;
        end

        % Button down function: MasterLedTab
        function masterLED_openungfcn(app, event)
            
app.cellnumber=1;
set(app.CurrentFOVEditField,'Value',app.cellnumber);  %reset each time run master.m
app.trialnumber=0;
set(app.CurrentTrialEditField,'Value',app.trialnumber);  %reset each time run master.m
app.repnumber=0;
set(app.CurrentRepetitionEditField,'Value',app.repnumber);  %reset each time run master.m


%%% creates ditital IO objects and adds a line %%%

app.currND=1;
app.BGstate=0;

app.optogenetics = 0;

app.sMs = daq.createSession('ni');
app.sMs.addDigitalChannel('Dev1','port0/line1', 'OutputOnly'); %sMs - start scan
app.sMs.outputSingleScan (0);

app.sMe = daq.createSession('ni');
app.sMe.addDigitalChannel('Dev1','port0/line2', 'OutputOnly'); %sMe - end scan
app.sMe.outputSingleScan (0);

app.sD = daq.createSession('ni');
app.sD.addDigitalChannel('Dev1','port0/line5', 'OutputOnly'); %sD - Digital signal returned to analog input 
app.sD.outputSingleScan (0);

app.sON = daq.createSession('ni');
app.sON.addDigitalChannel('Dev1','port0/line3', 'OutputOnly'); %sON - Light ON 
app.sON.outputSingleScan (0);

app.sOFF = daq.createSession('ni');
app.sOFF.addDigitalChannel('Dev1','port0/line4', 'OutputOnly'); %sOFF - Light OFF 
app.sOFF.outputSingleScan (0);

app.sRSTART = daq.createSession('ni');
app.sRSTART.addDigitalChannel('Dev1','port0/line6', 'OutputOnly'); %sRSTART - RSTART 
app.sRSTART.outputSingleScan (0);

app.sND = daq.createSession('ni');
app.sND.addDigitalChannel('Dev1','port0/line7', 'OutputOnly'); %sND - ND filter wheel 
app.sND.outputSingleScan (0);

app.sAIF = daq.createSession('ni');
app.sAIF.addAnalogInputChannel('Dev1','ai7','Voltage')
app.sAIF.addAnalogInputChannel('Dev1','ai4','Voltage')
app.sAIF.IsContinuous = true;
%sAIF.Rate=1000;

app.sAO1 = daq.createSession('ni');
app.sAO1.addAnalogOutputChannel('Dev1','ao1','Voltage');
app.sAO1.outputSingleScan(0);


app.sAO2 = daq.createSession('ni');
app.sAO2.addAnalogOutputChannel('Dev1','ao0','Voltage');
%addoutput(daq1,"Dev2","ao0","Voltage");             % set up an output channel to trigger cameras
% 
app.sAO2.outputSingleScan(0);

% sAO2 = daq("ni");
% sAO2.Rate=1000;
% addoutput(sAO2,"Dev1","ao0","Voltage");             % set up an output channel to trigger cameras
% 
% 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LogFileTab = uitab(app.TabGroup);
            app.LogFileTab.Title = 'Log File';
            app.CurrentDirectoryEditFieldLabel = uilabel(app.LogFileTab);
            app.CurrentDirectoryEditFieldLabel.Text = 'Current Directory ';
            app.CurrentDirectoryEditField = uieditfield(app.LogFileTab, 'text');
            app.CurrentDirectoryEditField.Tag = 'logfile_directory_disp_all';
            app.ChangeDirectoryButton = uibutton(app.LogFileTab, 'push');
            app.ChangeDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @Change_dir_Button, true);
            app.ChangeDirectoryButton.Tag = 'Change_Directory_B';
            app.ChangeDirectoryButton.Text = 'Change Directory';
            app.AddCommentButton = uibutton(app.LogFileTab, 'push');
            app.AddCommentButton.ButtonPushedFcn = createCallbackFcn(app, @add_comment_button, true);
            app.AddCommentButton.Tag = 'add_comment_B';
            app.AddCommentButton.Text = 'Add Comment';
            app.DisplayFileDirectoryButton = uibutton(app.LogFileTab, 'push');
            app.DisplayFileDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @display_logfile_button, true);
            app.DisplayFileDirectoryButton.Tag = 'display_file_B';
            app.DisplayFileDirectoryButton.Text = 'Display File Directory ';
            app.MasterLedTab = uitab(app.TabGroup);
            app.MasterLedTab.Title = 'Master Led';
            app.MasterLedTab.ButtonDownFcn = createCallbackFcn(app, @masterLED_openungfcn, true);
            app.NewFOVButton = uibutton(app.MasterLedTab, 'push');
            app.NewFOVButton.Tag = 'new_fov_B';
            app.NewFOVButton.Text = 'New FOV';
            app.NewTrialButton = uibutton(app.MasterLedTab, 'push');
            app.NewTrialButton.Tag = 'new_Trial_B';
            app.NewTrialButton.Text = 'New Trial';
            app.RepeatTrialButton = uibutton(app.MasterLedTab, 'push');
            app.RepeatTrialButton.Tag = 'repeat_trial_B';
            app.RepeatTrialButton.Text = 'Repeat Trial';
            app.CurrentFOVEditFieldLabel = uilabel(app.MasterLedTab);
            app.CurrentFOVEditFieldLabel.Text = 'CurrentFOV';
            app.CurrentFOVEditField = uieditfield(app.MasterLedTab, 'numeric');
            app.CurrentTrialEditFieldLabel = uilabel(app.MasterLedTab);
            app.CurrentTrialEditFieldLabel.Text = 'Current Trial';
            app.CurrentTrialEditField = uieditfield(app.MasterLedTab, 'numeric');
            app.CurrentRepetitionEditFieldLabel = uilabel(app.MasterLedTab);
            app.CurrentRepetitionEditFieldLabel.Text = 'Current Repetition';
            app.CurrentRepetitionEditField = uieditfield(app.MasterLedTab, 'numeric');
            app.SpotLedTab = uitab(app.TabGroup);
            app.SpotLedTab.Title = 'Spot Led';
            app.SpotLedTab.ButtonDownFcn = createCallbackFcn(app, @spotLEDauto_OpeningFcn, true);
            app.SpotColorDropDownLabel = uilabel(app.SpotLedTab);
            app.SpotColorDropDownLabel.Text = 'Spot Color';
            app.SpotColorDropDown = uidropdown(app.SpotLedTab);
            app.SpotColorDropDown.Items = {'White', 'Black', 'Red', 'Green', 'Blue'};
            app.SpotColorDropDown.ValueChangedFcn = createCallbackFcn(app, @SpotColorDropDownValueChanged, true);
            app.SpotColorDropDown.Tag = 'spot_color_pick';
            app.SpotColorDropDown.Value = 'White';
            app.NDStartEditFieldLabel = uilabel(app.SpotLedTab);
            app.NDStartEditFieldLabel.Text = 'ND Start';
            app.NDStartEditField = uieditfield(app.SpotLedTab, 'numeric');
            app.NDStartEditField.ValueChangedFcn = createCallbackFcn(app, @NDstart_change, true);
            app.NDStartEditField.Tag = 'NDstart';
            app.NDStartEditField.Value = 1;
            app.Panel = uipanel(app.SpotLedTab);
            app.NumberofspotsEditFieldLabel = uilabel(app.Panel);
            app.NumberofspotsEditFieldLabel.Text = 'Number of spots';
            app.NumberofspotsEditField = uieditfield(app.Panel, 'numeric');
            app.NumberofspotsEditField.ValueChangedFcn = createCallbackFcn(app, @value_num_spots_change, true);
            app.IntensityIndex1601EditFieldLabel = uilabel(app.Panel);
            app.IntensityIndex1601EditFieldLabel.Text = 'Intensity Index (1-601)';
            app.IntensityIndex1601EditField = uieditfield(app.Panel, 'numeric');
            app.IntensityIndex1601EditField.ValueChangedFcn = createCallbackFcn(app, @IntensityIndex_change, true);
            app.IntensityIndex1601EditField.Value = 6001;
            app.DurationEditFieldLabel = uilabel(app.Panel);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.Panel, 'numeric');
            app.DurationEditField.ValueChangedFcn = createCallbackFcn(app, @value_duration_change, true);
            app.InterstimEditFieldLabel = uilabel(app.Panel);
            app.InterstimEditFieldLabel.Text = 'Interstim';
            app.InterstimEditField = uieditfield(app.Panel, 'numeric');
            app.InterstimEditField.ValueChangedFcn = createCallbackFcn(app, @value_interstim_change, true);
            app.PretrigtimeEditFieldLabel = uilabel(app.Panel);
            app.PretrigtimeEditFieldLabel.Text = 'Pretrig time';
            app.PretrigtimeEditField = uieditfield(app.Panel, 'numeric');
            app.PretrigtimeEditField.ValueChangedFcn = createCallbackFcn(app, @pretrig_change, true);
            app.StimulusSiteButtonGroup = uibuttongroup(app.SpotLedTab);
            app.StimulusSiteButtonGroup.Title = 'Stimulus Site';
            app.onlyrightButton = uiradiobutton(app.StimulusSiteButtonGroup);
            app.onlyrightButton.Text = 'only right';
            app.onlyleftButton = uiradiobutton(app.StimulusSiteButtonGroup);
            app.onlyleftButton.Text = 'only left';
            app.leftrightButton = uiradiobutton(app.StimulusSiteButtonGroup);
            app.leftrightButton.Text = 'left & right';
            app.leftrightButton.Value = true;
            app.NDStopEditFieldLabel = uilabel(app.SpotLedTab);
            app.NDStopEditFieldLabel.Text = 'ND Stop';
            app.NDStopEditField = uieditfield(app.SpotLedTab, 'numeric');
            app.NDStopEditField.Limits = [1 12];
            app.NDStopEditField.ValueChangedFcn = createCallbackFcn(app, @NDstop_Fcn, true);
            app.NDStopEditField.Tag = 'NDstop';
            app.NDStopEditField.Value = 1;
            app.DurationsecEditFieldLabel = uilabel(app.SpotLedTab);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.SpotLedTab, 'numeric');
            app.DurationsecEditField.Editable = 'off';
            app.StartTrialButton = uibutton(app.SpotLedTab, 'push');
            app.StartTrialButton.ButtonPushedFcn = createCallbackFcn(app, @Start_Trial_Button, true);
            app.StartTrialButton.Text = 'Start Trial';
            app.RepeatTrialButton_2 = uibutton(app.SpotLedTab, 'push');
            app.RepeatTrialButton_2.ButtonPushedFcn = createCallbackFcn(app, @Repeat_Trial_Button, true);
            app.RepeatTrialButton_2.Text = 'Repeat Trial';
            app.StatusEditFieldLabel = uilabel(app.SpotLedTab);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.SpotLedTab, 'numeric');
            app.Lamp = uilamp(app.SpotLedTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LEDStimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end