classdef StioulationLED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        clearallButton               matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        FileTab                      matlab.ui.container.Tab
        acommentforthisexpiramentEditField  matlab.ui.control.EditField
        AddCommentButton             matlab.ui.control.Button
        BrowdeButton                 matlab.ui.control.Button
        DirectoryEditField           matlab.ui.control.EditField
        DirectoryEditFieldLabel      matlab.ui.control.Label
        MasterLesTab                 matlab.ui.container.Tab
        CurrentRepetitionEditField   matlab.ui.control.NumericEditField
        CurrentRepetitionEditFieldLabel  matlab.ui.control.Label
        CurrentTrialEditField        matlab.ui.control.NumericEditField
        CurrentTrialEditFieldLabel   matlab.ui.control.Label
        CurrentFOVEditField          matlab.ui.control.NumericEditField
        CurrentFOVEditFieldLabel     matlab.ui.control.Label
        RepeatTrialButton            matlab.ui.control.Button
        NewTrialButton               matlab.ui.control.Button
        NewFOVButton                 matlab.ui.control.Button
        SpotLedTab                   matlab.ui.container.Tab
        Lamp                         matlab.ui.control.Lamp
        RepeatTrialButton_2          matlab.ui.control.Button
        StartTrialButton             matlab.ui.control.Button
        PretrigtimeEditField         matlab.ui.control.NumericEditField
        PretrigtimeEditFieldLabel    matlab.ui.control.Label
        InterstimEditField           matlab.ui.control.NumericEditField
        InterstimEditFieldLabel      matlab.ui.control.Label
        DurationEditField            matlab.ui.control.NumericEditField
        DurationEditFieldLabel       matlab.ui.control.Label
        IntensityIndex1601EditField  matlab.ui.control.NumericEditField
        IntensityIndex1601EditFieldLabel  matlab.ui.control.Label
        NumberofspotsEditField       matlab.ui.control.NumericEditField
        NumberofspotsEditFieldLabel  matlab.ui.control.Label
        StatusEditField              matlab.ui.control.EditField
        StatusEditFieldLabel         matlab.ui.control.Label
        DurationsecEditField         matlab.ui.control.NumericEditField
        DurationsecEditFieldLabel    matlab.ui.control.Label
        NDstopEditField              matlab.ui.control.NumericEditField
        NDstopEditFieldLabel         matlab.ui.control.Label
        NDstartEditField             matlab.ui.control.NumericEditField
        NDstartEditFieldLabel        matlab.ui.control.Label
        StimulationSiteButtonGroup   matlab.ui.container.ButtonGroup
        rightleftButton              matlab.ui.control.RadioButton
        onlyleftButton               matlab.ui.control.RadioButton
        onlyrightButton              matlab.ui.control.RadioButton
        SpotColorDropDown            matlab.ui.control.DropDown
        SpotColorDropDownLabel       matlab.ui.control.Label
    end


    properties (Access = public)
        %%%%%%
        %properties for log file dir window
        %%%%%%
        logfilepath %this is path that the files is saved at currently
        logfile_directory%this is starting path

        %%%%%%
        %properties for master led window
        %%%%%%
        cellnumber
        trialnumber %this is the trial number
        repnumber %this is the repetition number

        %%%%%%
        %properties for spot led window
        %%%%%%
        tests %this is the test numbet NumberofspotsEditField,
        time %this is time
        tween
        pretrig
        IntensityIndex
        SIDEstate %the state
        clampex_duration%
        spot_color
        side%stimulus side

        whiteonblack%idk???

        ND
        currND
        NDstart%when to start
        NDstop%when to stop
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %the led stuff i think

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        BGstate
        sS
        sS2
        sND
        sMs
        sMe
        sD
        sD2
        sAIF
        fid1
        lh
        sAO1
        LOvoltage
        LOIndex
        LOamper
        sON
        sOFF
        sRSTART
        sAO2
        %optogenetics =>i dont need optogenetics
    end

    methods (Access = private)

       
    end
    
    methods (Access = public)
        
         function [] = increment_trial(app)
            % --- Increment trial number
            app.trialnumber=app.trialnumber+1;
            %increment trial number and display on user interface
            app.CurrentTrialEditField.Value=app.trialnumber;
            %reset each time incrementing cell number   (repnumber)
            app.repnumber=1;
            app.CurrentRepetitionEditField.Value=app.repnumber;%reset each time incrementing cell number
            %define path and filename of log file for this cell, accessible (global) to all modules
            app.logfilepath = strcat(app.logfile_directory,'\fov#',num2str(app.cellnumber,'%02d'),'_','trial#',num2str(app.trialnumber,'%02d'),'_','rep#',num2str(app.repnumber,'%02d'),'_');

        end

        function []=increment_rep(app)
            % --- Increment repetition number
            app.repnumber=app.repnumber+1;
            %increment trial number and display on user interface
            app.CurrentRepetitionEditField.Value=app.repnumber;
            %define path and filename of log file for this cell, accessible (global) to all modules
            app.logfilepath = strcat(app.logfile_directory,'\fov#',num2str(app.cellnumber,'%02d'),'_','trial#',num2str(app.trialnumber,'%02d'),'_','rep#',num2str(app.repnumber,'%02d'),'_');


        end

        function spotrun(app)
            %jheapcl %what is this

            pulseND(app.currND, app.ND);
            pause (1)
            app.currND=app.ND;

            app.fid1 = fopen('log.bin','w');
            app.lh = app.sAIF.addlistener('DataAvailable',@(src, event)logData(src, event, app.fid1));

            %log start time to logfile
            startTime=datatime("now",21);
            start_time = datetime("now");     %save start time to calculate elapsed time later
            expType='SP';


            if exist([app.logfilepath, 'logFile.mat'],'file')
                warndlg('Warning - A file with a similar name already exists');
                uiwait(gcf);
                delete(app.lh);
                fclose(app.fid1);
                return
            end
            %initialize local variables
            tested = [];  % declare array to hold sizes (rad) tested for logfile
            outtahere = 0;
            on = 1;

            %show the user via the GUI that the trial is running
            app.StatusEditField.Value='running';  %update the string to be displayed

            feval(fh_now_playing_readout_Callback);  %invoke callback function for the readout
            guidata(hSpotGui, handles);   % Update handles structure

            %create vector for ramp
            ramp_vector = round(linspace(1, app.IntensityIndex, app.time/2));  %first half climing intensity
            %add decreasing intensity
            ramp_vector = [ramp_vector flip(ramp_vector)];

            tStart = tic;


            tElapsed = toc(tStart);
            pause(app.pretrig - tElapsed);
            if app.optogenetics
                app.sAO2.outputSingleScan(0); %15 sec of stimulus before the trile
            end
            for i = 1 : app.tests

                [~, ~,keyCode] = KbCheck;

                if (on == 1)

                    %%%%%%%%%%%%%%%%
                    app.sD.outputSingleScan(1);
                    app.sON.outputSingleScan(1); pause(0.1); app.sON.outputSingleScan(0);
                    app.sAO1.outputSingleScan(app.LOvoltage(1));

                    %%%%%%%%%%%%%%%%

                    tested = [tested,1]; %append rad to 'tested' scalar
                    %rad = rad + step;
                    on = 0;


                    % We're in the holding period while spotLEDauto is being displayed.
                    % Chop interval ('time') into 1 s intervals to permit periodic check for
                    % user ESC keystroke, which triggers an abort
                    for k = 1:(app.time * 1)
                        app.sAO1.outputSingleScan(app.LOvoltage(ramp_vector(k))); %each seconed increas the intensity of light
                        pause(0.985)
                        [~,~,keyCode] = KbCheck;
                        keystrike = KbName(keyCode);
                        if strcmp(keystrike,'esc')
                            outtahere = 1;
                            %                 delete(lh);
                            %                 fclose(fid1);
                            break;

                        end
                    end
                end
                if (on==0)
                    if (outtahere > 0)     %bail out if user has hit ESC
                        break;
                    end


                    %%%%%%%%%%%%%%%%
                    app.sD.outputSingleScan(0);
                    app.sOFF.outputSingleScan(1); pause(0.1); app.sOFF.outputSingleScan(0);
                    app.sAO1.outputSingleScan(0);

                    if app.optogenetics
                        %turn on laser in the dark part of every 2 triels
                        if ~mod(i,2)
                            app.sAO2.outputSingleScan(5); %10 sec of stimulus between the triles
                        end
                    end


                    %%%%%%%%%%%%%%%%

                    % interstim interval
                    % chop into 1 s intervals to permit periodic check for
                    % user ESC keystroke, which triggers an abort
                    for b = 1:(app.tween*1)
                        pause(0.985)

                        [~,~,keyCode] = KbCheck;
                        keystrike = KbName(keyCode);
                        if strcmp(keystrike,'esc')
                            outtahere = 2;   %will exploit value of outtahere
                            %                 delete(lh);
                            %                 fclose(fid1);
                            break;           %when logging nature of abort to logfile
                        end
                    end
                    if outtahere > 0
                        %             delete(lh);
                        %             fclose(fid1);
                        %sS.outputSingleScan (1);
                        %sS.outputSingleScan (0);
                        break
                    end
                    if app.optogenetics
                        app.sAO2.outputSingleScan(0); %10 sec of stimulus every 2 triels
                    end

                    on = 1;
                end

            end

            %%%%%%%%%%%%%%%%%%%%%%
            app.sD.outputSingleScan(0);
            app.sAIF.stop();
            app.sRSTART.outputSingleScan(0);
            app.sMe.outputSingleScan(1); pause(0.1); app.sMe.outputSingleScan(0);
            app.sAO1.outputSingleScan(0);
            if app.optogenetics
                app.sAO2.outputSingleScan(0); %15 sec of stimulus before the trile
            end


            %%%%%%%%%%%%%%%%%%%%%%

            %code to display current value of radius on the GUI
            app.StatusEditField.Value='done'; %update the string to be displayed
            feval(fh_now_playing_readout_Callback);  %invoke callback function for the readout
            guidata(hSpotGui, handles);   % Update handles structure

            delete(app.lh);
            fclose(app.fid1);

            fid2 = fopen('log.bin','r');
            [data,~]= fread(fid2,[3,inf],'double');
            fclose(fid2);
            data=data';
            data(:,2)=max(data(:,2))-data(:,2);
            frameWaveLog=data;

            %update log file with stoptime, elapsed time and parameters used
            end_time = datetime("now");
            elapsed = datetime(end_time,start_time);

            [~, stim_num] = size(tested);
            switch outtahere
                case 0
                    abort_notice = '';
                case 1
                    abort_notice = strcat(['  << aborted during stimulus ',num2str(stim_num), ' >>']);
                case 2
                    abort_notice = strcat(['  << aborted during interstim interval after stim ',num2str(stim_num), ' >>']);
            end

            endTime=datetime("now",21);
            triel_voltage = app.LOvoltage(ramp_vector);
            save([app.logfilepath, 'logFile.mat'],'expType','cellnumber','trialnumber','repnumber','startTime','endTime','elapsed','tests','time','tween','whiteonblack', 'pretrig', 'IntensityIndex', 'ND', 'SIDEstate','frameWaveLog','abort_notice', 'triel_voltage');


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowdeButton
        function Change_Dir_Button_w1(app, event)
            %this function will change the directery which the file will be
            app.logfile_directory = uigetdir('C:\Users\owner\Desktop');%uigetdir('D:\in vivo data');  %user specifies directory through browsing dialog box
            app.DirectoryEditField.Value=app.logfile_directory;%setting dir and showing the user which directory was selected
            app.logfilepath = strcat(app.logfile_directory,'\fov#',num2str(app.cellnumber,'%02d'),'_','trial#',num2str(app.trialnumber,'%02d'),'_','rep#',num2str(app.repnumber,'%02d'),'_');
        end

        % Button pushed function: AddCommentButton
        function AddCommentButton_W1(app, event)

        end

        % Button down function: MasterLesTab
        function OpenMasterLedPage(app, event)
            %             this function defines the connection to the DAQ
            app.cellnumber=1;
            set(app.CurrentFOVEditField,'Value',app.cellnumber);  %reset each time run master.m
            app.trialnumber=0;
            set(app.CurrentTrialEditField,'Value',app.trialnumber);  %reset each time run master.m
            app.repnumber=0;
            set(app.CurrentRepetitionEditField,'Value',app.repnumber);  %reset each time run master.m

            %%% creates ditital IO objects and adds a line %%%

            app.currND=1;
            app.BGstate=0;

            
            app.sMs = daq.createSession('ni');
            app.sMs.addDigitalChannel('Dev1','port0/line1', 'OutputOnly'); %sMs - start scan
            app.sMs.outputSingleScan (0);

            app.sMe = daq.createSession('ni');
            app.sMe.addDigitalChannel('Dev1','port0/line2', 'OutputOnly'); %sMe - end scan
            app.sMe.outputSingleScan (0);

            app.sD = daq.createSession('ni');
            app.sD.addDigitalChannel('Dev1','port0/line5', 'OutputOnly'); %sD - Digital signal returned to analog input
            app.sD.outputSingleScan (0);

            app.sON = daq.createSession('ni');
            app.sON.addDigitalChannel('Dev1','port0/line3', 'OutputOnly'); %sON - Light ON
            app.sON.outputSingleScan (0);

            app.sOFF = daq.createSession('ni');
            app.sOFF.addDigitalChannel('Dev1','port0/line4', 'OutputOnly'); %sOFF - Light OFF
            app.sOFF.outputSingleScan (0);

            app.sRSTART = daq.createSession('ni');
            app.sRSTART.addDigitalChannel('Dev1','port0/line6', 'OutputOnly'); %sRSTART - RSTART
            app.sRSTART.outputSingleScan (0);

            app.sND = daq.createSession('ni');
            app.sND.addDigitalChannel('Dev1','port0/line7', 'OutputOnly'); %sND - ND filter wheel
            app.sND.outputSingleScan (0);

            app.sAIF = daq.createSession('ni');
            app.sAIF.addAnalogInputChannel('Dev1','ai7','Voltage')
            app.sAIF.addAnalogInputChannel('Dev1','ai4','Voltage')
            app.sAIF.IsContinuous = true;
            %sAIF.Rate=1000;

            app.sAO1 = daq.createSession('ni');
            app.sAO1.addAnalogOutputChannel('Dev1','ao1','Voltage');
            app.sAO1.outputSingleScan(0);


            app.sAO2 = daq.createSession('ni');
            app.sAO2.addAnalogOutputChannel('Dev1','ao0','Voltage');
            %addoutput(daq1,"Dev2","ao0","Voltage");             % set up an output channel to trigger cameras
            %
            app.sAO2.outputSingleScan(0);

            % sAO2 = daq("ni");
            % sAO2.Rate=1000;
            % addoutput(sAO2,"Dev1","ao0","Voltage");             % set up an output channel to trigger cameras
            %
            %
        end

        % Button pushed function: NewTrialButton
        function NewTrialButton_W2(app, event)
            %increment trial number and display on user interface
            app.trialnumber = app.trialnumber+1;
            app.CurrentTrialEditField.Value=app.trialnumber;%set
            app.repnumber=0;
            app.CurrentRepetitionEditField.Value=app.repnumber;  %reset each time incrementing cell number
            %define path and filename of log file for this cell, accessible (global) to all modules
            app.logfilepath = strcat(app.logfile_directory,'\fov#',num2str(app.cellnumber,'%02d'),'_','trial#',num2str(tapp.rialnumber,'%02d'),'_','rep#',num2str(app.repnumber,'%02d'),'_');
        end

        % Button pushed function: NewFOVButton
        function NewCellButton_W2(app, event)
            %increment cell number and display on user interface
            app.cellnumber = app.cellnumber+1;
            app.CurrentFOVEditField.Value=app.cellnumber;%set
            app.trialnumber=0;
            app.CurrentTrialEditField.Value=app.trialnumber;  %reset each time incrementing cell number
            app.repnumber=0;
            app.CurrentRepetitionEditField.Value=app.repnumber;  %reset each time incrementing cell number
            %define path and filename of log file for this cell, accessible (global) to all modules
            app.logfilepath = strcat(app.logfile_directory,'\fov#',num2str(app.cellnumber,'%02d'),'_','trial#',num2str(tapp.rialnumber,'%02d'),'_','rep#',num2str(app.repnumber,'%02d'),'_');

        end

        % Button pushed function: RepeatTrialButton
        function RepeatTrialButton_W2(app, event)
            %increment repetition number and display on user interface
            app.repnumber=0;
            app.CurrentRepetitionEditField.Value=app.repnumber;  %reset each time incrementing cell number
            %define path and filename of log file for this cell, accessible (global) to all modules
            app.logfilepath = strcat(app.logfile_directory,'\fov#',num2str(app.cellnumber,'%02d'),'_','trial#',num2str(tapp.rialnumber,'%02d'),'_','rep#',num2str(app.repnumber,'%02d'),'_');

        end

        % Button down function: SpotLedTab
        function OpenSpotLedPage_W3(app, event)
            %this function is a restsrter of spot led page
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.LOIndex=1:6001; %idk what this is
            app.LOvoltage=linspace(0,5,6001);
            app.LOamper=linspace(0,1200,6001); %idk what this is

            app.tests = 20;       %6 for focal cell, 2 for depth series, 5 for Rbp, 10 for IV, 4 for OFF alpha, 4 for excitatory currents ipRGCs
            app.time = 10;      % 30 for iGluSnFR, 15 for Rbp, 4 for IV
            app.tween = 10;      %15 for focal cell, 30 for depth series,7 for IV, 30 for OFF alpha
            app.pretrig = 15;    % 0.1721 for IV
            app.IntensityIndex = 6001;
            app.LOvoltage = app.LOvoltage;
            safety_factor = 1;
            app.whiteonblack = 1;
            app.spot_color = "White";       %white
            app.side = 3;  %both sides
            app.ND=1;%app.currND;
            app.Lamp.Color=[1.00,1.00,1.00];
            app.clampex_duration = app.pretrig + (app.tests * (app.time + app.tween)) + safety_factor;
            set(app.NumberofspotsEditField,'Value', app.tests);
            set(app.DurationEditField,'Value',app.time);
            set(app.InterstimEditField,'Value',app.tween);
            set(app.PretrigtimeEditField ,'Value', app.pretrig);
            set(app.IntensityIndex1601EditField,'Value', app.IntensityIndex);
            set(app.NDstartEditField,'Value', app.ND);
            set(app.DurationsecEditField,'Value', app.clampex_duration);
            set(app.StatusEditField,'Value','pre-run');
            set(app.SpotColorDropDown,'Value', app.spot_color );
            app.rightleftButton.Value=1;
            app.SIDEstate=3;
        end

        % Value changed function: NDstartEditField
        function ND_StartChangefnc_W3(app, event)

            temp_ND = app.NDstartEditField.Value;%get user input
            %lobal NDstart

            if isempty(temp_ND) || temp_ND <= 0 || temp_ND > 12
                beep;
                temp_ND = 1;
            end

            app.NDstart = temp_ND;
            app.NDstartEditField.Value=app.NDstart;

        end

        % Value changed function: NDstopEditField
        function ND_StopChangefnc_W3(app, event)
            temp_ND=app.NDstopEditField.Value;%get user input
            if isempty(temp_ND) || temp_ND <= 0 || temp_ND > 12
                beep;
                if temp_ND > 12
                    errordlg('ND stop cant be more then 12','File Error');
                    temp_ND = 1;
                else
                    errordlg('ND stop incorrect input','File Error');
                    temp_ND = 1;
                end

                temp_ND = 1;
            end

            app.NDstop= temp_ND;%set
            app.NDstopEditField.Value=app.NDstop;
        end

        % Value changed function: NumberofspotsEditField
        function NumSpotsChangeval_W3(app, event)

            %TESTS (i.e., number of sizes)
            temp_value = app.NumberofspotsEditField.Value;%get

            %error trap - tests must be a number and >0 and <=20
            if isempty(temp_value) || temp_value < 1 || temp_value > 200
                beep;
                if temp_value > 200
                    temp_value = 200;
                else
                    temp_value = 1;
                end
            end

            app.tests = temp_value;%set
            app.NumberofspotsEditField.Value=app.tests;
            %calculate best duration for clampex protocol
            %safety factor is to make sure clampex protocol duration catches everything
            safety_factor = 1;
            app.clampex_duration = app.pretrig + (app.pretrig * (app.time + app.tween)) + safety_factor;
            app.DurationEditField=app.clampex_duration;

        end

        % Value changed function: DurationEditField
        function DurationChangeval_W3(app, event)
            %TIME (i.e. duration of each spotLEDauto presentation)
            % get user input for stimulus duration ('time')
            temp_value = app.DurationEditField.Value;%get

            %error trap - time must be a number and >10 ms and <=30 sec
            if isempty(temp_value) || temp_value < 0.01 || temp_value > 10000
                beep;
                if temp_value > 10000
                    temp_value = 30;
                else
                    temp_value = 2;
                end

                app.time = temp_value;
            end

            app.time = temp_value;%set
            app.DurationEditField.Value=app.time;
            %calculate and display best duration for clampex protocol
            %safety factor is to make sure clampex protocol duration catches everything
            safety_factor = 1;
            app.clampex_duration = app.pretrig + (app.tests * (app.time + app.tween)) + safety_factor;
            app.DurationsecEditField=app.clampex_duration;

        end

        % Value changed function: InterstimEditField
        function InterstimChangeval_W3(app, event)
            %TWEEN (i.e., interstim interval)
            temp_tween_value = app.InterstimEditField.Value;%get

            %error trap - ISI ('tween') must be a number and >0.1 s and <=30 sec
            if isempty(temp_tween_value) || temp_tween_value < 0.1 || temp_tween_value > 1000
                beep;
                %     if temp_tween > 1000
                %         temp_tween = 30;
                %     else
                %         temp_tween = 1;
                %     end

                app.tween=app.InterstimEditField.Value;
            end

            app.tween = temp_tween;%set
            app.InterstimEditField.Value=app.tween;
            %calculate and display best duration for clampex protocol
            %safety factor is to make sure clampex protocol duration catches everything
            safety_factor = 1;
            app.clampex_duration = app.pretrig + (app.tests * (app.time + app.tween)) + safety_factor;
            app.DurationsecEditField=app.clampex_duration;


        end

        % Value changed function: PretrigtimeEditField
        function PretringTimeChangeval_W3(app, event)
            %PRETRIGGER
            temp_pretrig = app.PretrigtimeEditField.Value;%get

            %error trap - pretrig must be a number and >0 and <=30
            if isempty(temp_pretrig) || temp_pretrig <= 0 || temp_pretrig > 1000
                beep;
                if temp_pretrig > 1000
                    temp_pretrig = 30;
                else
                    temp_pretrig = 1;
                end
            end
            app.pretrig = temp_pretrig;%set
            app.PretrigtimeEditField.Value=app.pretrig;
            %calculate and display best duration for clampex protocol
            %safety factor is to make sure clampex protocol duration catches everything
            safety_factor = 1;
            app.clampex_duration = app.pretrig + (app.tests * (app.time + app.tween)) + safety_factor;
            app.DurationsecEditField=app.clampex_duration;

            % --- Executes during object creation, after setting all properties.

        end

        % Value changed function: IntensityIndex1601EditField
        function IntensityChangeval_W3(app, event)
            temp_index = app.IntensityIndex1601EditField.Value;%get user input

            %error trap - IntensityIndex must be a number and >0 and <=1
            if isempty(temp_index)
                temp_index = 1;
            elseif temp_index > 6001
                temp_index = 6001;
            elseif temp_index < 1
                temp_index = 1;
            end

            app.IntensityIndex = temp_index;%set
            app.IntensityIndex1601EditField.Value=app.IntensityIndex;

        end

        % Value changed function: SpotColorDropDown
        function SpotColorChangeval_W3(app, event)
            %PICK SPOTLEDAUTO COLOR
            switch app.SpotColorDropDown.Value%get
                case 'White'
                    app.spot_color = [255 255 255];  %white
                    app.Lamp.Color=[1.00,1.00,1.00];
                case 'Black'
                    app.spot_color = [0 0 0];        %black
                    app.Lamp.Color=[0.00,0.00,0.00];
                case 'Red'
                    app.spot_color = [255 0 0];      %red
                    app.Lamp.Color=[1.00,0.00,0.00];
                case 'Green'
                    app.spot_color = [0 255 0];      %green
                    app.Lamp.Color=[0.00,1.00,0.00];
                case 'Blue'
                    app.spot_color = [0 0 255];      %blue
                    app.Lamp.Color=[0.00,0.00,1.00];
            end
        end

        % Button pushed function: StartTrialButton
        function START_TrialButton_W3(app, event)
            %START BUTTON
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            for i=app.NDstart:-1:app.NDstop
                 try
                     delete(app.lh); fclose(app.fid1); fclose(fid2);
                 catch 
                 end
                app.ND=i;
                increment_trial(app)
                spotrun(app)
            end
        end

        % Button pushed function: RepeatTrialButton_2
        function REPEAT_TrialButton_W3(app, event)
            %repeat trial button
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            try
                delete(app.lh);
                fclose(app.fid1);
                fclose(fid2);
            catch
            end
            increment_rep(app)
            spotrun(app)
        end

        % Selection changed function: StimulationSiteButtonGroup
        function StimulationSiteButtonGroupSelectionChanged(app, event)
            if app.onlyrightButton.Value==1
                app.side=1;
            elseif app.onlyleftButton.Value==1
                app.side=2;
            elseif app.rightleftButton.Value==1
                app.side=3;
            end
        end

        % Button pushed function: clearallButton
        function ClearAll_Button_W(app, event)
            %this function clears all channols
            clc
            clear all

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FileTab = uitab(app.TabGroup);
            app.FileTab.Title = 'File';
            app.DirectoryEditFieldLabel = uilabel(app.FileTab);
            app.DirectoryEditFieldLabel.Text = ' Directory';
            app.DirectoryEditField = uieditfield(app.FileTab, 'text');
            app.DirectoryEditField.Editable = 'off';
            app.BrowdeButton = uibutton(app.FileTab, 'push');
            app.BrowdeButton.ButtonPushedFcn = createCallbackFcn(app, @Change_Dir_Button_w1, true);
            app.BrowdeButton.Text = 'Browde...';
            app.AddCommentButton = uibutton(app.FileTab, 'push');
            app.AddCommentButton.ButtonPushedFcn = createCallbackFcn(app, @AddCommentButton_W1, true);
            app.AddCommentButton.Text = 'Add Comment';
            app.acommentforthisexpiramentEditField = uieditfield(app.FileTab, 'text');
            app.acommentforthisexpiramentEditField.Value = 'a comment for this expirament...';
            app.MasterLesTab = uitab(app.TabGroup);
            app.MasterLesTab.Title = 'Master Les';
            app.MasterLesTab.ButtonDownFcn = createCallbackFcn(app, @OpenMasterLedPage, true);
            app.NewFOVButton = uibutton(app.MasterLesTab, 'push');
            app.NewFOVButton.ButtonPushedFcn = createCallbackFcn(app, @NewCellButton_W2, true);
            app.NewFOVButton.Text = 'New FOV';
            app.NewTrialButton = uibutton(app.MasterLesTab, 'push');
            app.NewTrialButton.ButtonPushedFcn = createCallbackFcn(app, @NewTrialButton_W2, true);
            app.NewTrialButton.Text = 'New Trial';
            app.RepeatTrialButton = uibutton(app.MasterLesTab, 'push');
            app.RepeatTrialButton.ButtonPushedFcn = createCallbackFcn(app, @RepeatTrialButton_W2, true);
            app.RepeatTrialButton.Text = 'Repeat Trial';
            app.CurrentFOVEditFieldLabel = uilabel(app.MasterLesTab);
            app.CurrentFOVEditFieldLabel.Text = 'Current FOV';
            app.CurrentFOVEditField = uieditfield(app.MasterLesTab, 'numeric');
            app.CurrentTrialEditFieldLabel = uilabel(app.MasterLesTab);
            app.CurrentTrialEditFieldLabel.Text = 'Current Trial';
            app.CurrentTrialEditField = uieditfield(app.MasterLesTab, 'numeric');
            app.CurrentRepetitionEditFieldLabel = uilabel(app.MasterLesTab);
            app.CurrentRepetitionEditFieldLabel.Text = 'Current Repetition';
            app.CurrentRepetitionEditField = uieditfield(app.MasterLesTab, 'numeric');
            app.SpotLedTab = uitab(app.TabGroup);
            app.SpotLedTab.Title = 'Spot Led';
            app.SpotLedTab.ButtonDownFcn = createCallbackFcn(app, @OpenSpotLedPage_W3, true);
            app.SpotColorDropDownLabel = uilabel(app.SpotLedTab);
            app.SpotColorDropDownLabel.Text = 'Spot Color';
            app.SpotColorDropDown = uidropdown(app.SpotLedTab);
            app.SpotColorDropDown.Items = {'White', 'Black', 'Red', 'Green', 'Blue'};
            app.SpotColorDropDown.ValueChangedFcn = createCallbackFcn(app, @SpotColorChangeval_W3, true);
            app.SpotColorDropDown.Value = 'White';
            app.StimulationSiteButtonGroup = uibuttongroup(app.SpotLedTab);
            app.StimulationSiteButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StimulationSiteButtonGroupSelectionChanged, true);
            app.StimulationSiteButtonGroup.Title = 'Stimulation Site';
            app.onlyrightButton = uiradiobutton(app.StimulationSiteButtonGroup);
            app.onlyrightButton.Text = 'only right';
            app.onlyleftButton = uiradiobutton(app.StimulationSiteButtonGroup);
            app.onlyleftButton.Text = 'only left';
            app.rightleftButton = uiradiobutton(app.StimulationSiteButtonGroup);
            app.rightleftButton.Text = 'right & left';
            app.rightleftButton.Value = true;
            app.NDstartEditFieldLabel = uilabel(app.SpotLedTab);
            app.NDstartEditFieldLabel.Text = 'ND start';
            app.NDstartEditField = uieditfield(app.SpotLedTab, 'numeric');
            app.NDstartEditField.ValueChangedFcn = createCallbackFcn(app, @ND_StartChangefnc_W3, true);
            app.NDstopEditFieldLabel = uilabel(app.SpotLedTab);
            app.NDstopEditFieldLabel.Text = 'ND stop';
            app.NDstopEditField = uieditfield(app.SpotLedTab, 'numeric');
            app.NDstopEditField.ValueChangedFcn = createCallbackFcn(app, @ND_StopChangefnc_W3, true);
            app.DurationsecEditFieldLabel = uilabel(app.SpotLedTab);
            app.DurationsecEditFieldLabel.Text = 'Duration sec';
            app.DurationsecEditField = uieditfield(app.SpotLedTab, 'numeric');
            app.DurationsecEditField.Editable = 'off';
            app.StatusEditFieldLabel = uilabel(app.SpotLedTab);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.SpotLedTab, 'text');
            app.StatusEditField.Editable = 'off';
            app.NumberofspotsEditFieldLabel = uilabel(app.SpotLedTab);
            app.NumberofspotsEditFieldLabel.Text = 'Number of spots';
            app.NumberofspotsEditField = uieditfield(app.SpotLedTab, 'numeric');
            app.NumberofspotsEditField.ValueChangedFcn = createCallbackFcn(app, @NumSpotsChangeval_W3, true);
            app.IntensityIndex1601EditFieldLabel = uilabel(app.SpotLedTab);
            app.IntensityIndex1601EditFieldLabel.Text = 'Intensity Index (1-601)';
            app.IntensityIndex1601EditField = uieditfield(app.SpotLedTab, 'numeric');
            app.IntensityIndex1601EditField.ValueChangedFcn = createCallbackFcn(app, @IntensityChangeval_W3, true);
            app.IntensityIndex1601EditField.Value = 6001;
            app.DurationEditFieldLabel = uilabel(app.SpotLedTab);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.SpotLedTab, 'numeric');
            app.DurationEditField.ValueChangedFcn = createCallbackFcn(app, @DurationChangeval_W3, true);
            app.InterstimEditFieldLabel = uilabel(app.SpotLedTab);
            app.InterstimEditFieldLabel.Text = 'Interstim';
            app.InterstimEditField = uieditfield(app.SpotLedTab, 'numeric');
            app.InterstimEditField.ValueChangedFcn = createCallbackFcn(app, @InterstimChangeval_W3, true);
            app.PretrigtimeEditFieldLabel = uilabel(app.SpotLedTab);
            app.PretrigtimeEditFieldLabel.Text = 'Pretrig time';
            app.PretrigtimeEditField = uieditfield(app.SpotLedTab, 'numeric');
            app.PretrigtimeEditField.ValueChangedFcn = createCallbackFcn(app, @PretringTimeChangeval_W3, true);
            app.StartTrialButton = uibutton(app.SpotLedTab, 'push');
            app.StartTrialButton.ButtonPushedFcn = createCallbackFcn(app, @START_TrialButton_W3, true);
            app.StartTrialButton.Text = 'Start Trial';
            app.RepeatTrialButton_2 = uibutton(app.SpotLedTab, 'push');
            app.RepeatTrialButton_2.ButtonPushedFcn = createCallbackFcn(app, @REPEAT_TrialButton_W3, true);
            app.RepeatTrialButton_2.Text = 'Repeat Trial';
            app.Lamp = uilamp(app.SpotLedTab);
            app.clearallButton = uibutton(app.UIFigure, 'push');
            app.clearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAll_Button_W, true);
            app.clearallButton.Text = 'clear all';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StioulationLED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end